classdef GUI_mini_proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Robot_Miguel                    matlab.ui.Figure
        Image                           matlab.ui.control.Image
        Button                          matlab.ui.control.Button
        AdelanteButton                  matlab.ui.control.Button
        PararButton                     matlab.ui.control.Button
        AtrsButton                      matlab.ui.control.Button
        VelocidadLabel                  matlab.ui.control.Label
        VelocidadKnob                   matlab.ui.control.Knob
        Button_2                        matlab.ui.control.Button
        DireccinSliderLabel             matlab.ui.control.Label
        DireccinSlider                  matlab.ui.control.Slider
        Button_3                        matlab.ui.control.Button
        PosicinSensordeTemperaturaKnobLabel  matlab.ui.control.Label
        PosicinSensordeTemperaturaKnob  matlab.ui.control.Knob
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Knob                            matlab.ui.control.Knob
        Image4                          matlab.ui.control.Image
        BDiscon                         matlab.ui.control.Button
        PORTDropDownLabel               matlab.ui.control.Label
        PortList                        matlab.ui.control.DropDown
        Image5                          matlab.ui.control.Image
        ConexinLampLabel                matlab.ui.control.Label
        ConnLamp                        matlab.ui.control.Lamp
        BCon                            matlab.ui.control.Button
        Label_amb                       matlab.ui.control.Label
        Label_obj                       matlab.ui.control.Label
        TemperaturaLabel                matlab.ui.control.Label
        AmbienteLabel                   matlab.ui.control.Label
        ObjetoLabel                     matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        VelociddadmotorDCizquierdoLabel  matlab.ui.control.Label
        VelociddadmotorDCizquierdoGauge  matlab.ui.control.NinetyDegreeGauge
        VelocidadmotorDCderechoGaugeLabel  matlab.ui.control.Label
        VelocidadmotorDCderechoGauge    matlab.ui.control.NinetyDegreeGauge
        adelante                        matlab.ui.control.Lamp
        parar                           matlab.ui.control.Lamp
        atras                           matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        s % serial port
        t % timer (for update GUI and send data)
        TIME_STEP = 0.5 % fixed time step of calling SendData function
        GREEN_COLOR = [0 1 0]
        RED_COLOR = [1 0 0]
        enviar = zeros(1, 6)
        temp_amb = 0;
        temp_obj = 0;
        conectado = 0;
        rpm1 = 0;
        rpm2 = 0;
    end
    
    methods (Access = private)
        
        function timerFunction(app)
            fwrite(app.s, app.enviar, 'uint8', 'async')
            app.temp_amb = fscanf(app.s,'%f');
            app.temp_obj = fscanf(app.s,'%f');
            app.rpm1 = fscanf(app.s,'%f');
            app.rpm2 = fscanf(app.s,'%f');
            app.Label_amb.Text = string(app.temp_amb);
            app.Label_obj.Text = string(app.temp_obj);
            app.VelocidadmotorDCderechoGauge.Value = app.rpm1;
            app.VelociddadmotorDCizquierdoGauge.Value = app.rpm2;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set up timer
            app.t = timer('Period',0.20,...
                'ExecutionMode', 'fixedSpacing');%,'TasksToExecute',1);
            app.t.TimerFcn = @(~,~) app.timerFunction;
            app.t.StartDelay = 3;
            app.adelante.Color = app.RED_COLOR;
            app.parar.Color = app.RED_COLOR;
            app.atras.Color = app.RED_COLOR;
            
            % looking for available serial ports
            % (Instrument Control Toolbox required)
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortList.Items = p.AvailableSerialPorts;
            end
            app.enviar(1) = 255;
            app.enviar(2) = 90;
            app.enviar(3) = 0;
            app.enviar(4) = 100;
            app.enviar(5) = 0;
            app.enviar(6) = 40;
            
        end

        % Button pushed function: BCon
        function BConButtonPushed(app, event)
            app.s = serial(app.PortList.Value);
            fopen(app.s);
            start(app.t);
            app.ConnLamp.Color = app.GREEN_COLOR;
            app.conectado = 1;
        end

        % Button pushed function: BDiscon
        function BDisconButtonPushed(app, event)
            stop(app.t);
            fclose(app.s);
            app.conectado = 0;
            app.ConnLamp.Color = app.RED_COLOR;
            app.adelante.Color = app.RED_COLOR;
            app.parar.Color = app.RED_COLOR;
            app.atras.Color = app.RED_COLOR;
            app.enviar(5) = 2;
            
        end

        % Value changed function: DireccinSlider
        function DireccinSliderValueChanged(app, event)
            value = app.DireccinSlider.Value;
            if value==0
                app.enviar(2) = 90;
            else
                app.enviar(2) = 90 + value; 
            end

        end

        % Value changed function: VelocidadKnob
        function VelocidadKnobValueChanged(app, event)
            value = app.VelocidadKnob.Value;
            app.enviar(6) = value;            
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            app.enviar(3) = value;
            %fwrite(app.s, app.enviar, 'uint8', 'async')
        end

        % Value changed function: PosicinSensordeTemperaturaKnob
        function PosicinSensordeTemperaturaKnobValueChanged(app, event)
            value = app.PosicinSensordeTemperaturaKnob.Value;
            if value==0
                app.enviar(4) = 100;
            elseif value > 0
                app.enviar(4) = ((value-0)*(200-100))/((360-0))+100;
            elseif value < 0
                app.enviar(4) = ((value+360)*(100-0))/(0+360) + 0;
            end
            %fwrite(app.s, app.enviar, 'uint8', 'async')
        end

        % Close request function: Robot_Miguel
        function Robot_MiguelCloseRequest(app, event)
            if app.conectado == 1
                stop(app.t);
                fclose(app.s);
            end
            delete(app)
        end

        % Button pushed function: AdelanteButton
        function AdelanteButtonPushed(app, event)
            app.enviar(5) = 1;
            app.adelante.Color = app.GREEN_COLOR;
            app.parar.Color = app.RED_COLOR;
            app.atras.Color = app.RED_COLOR;
        end

        % Button pushed function: PararButton
        function PararButtonPushed(app, event)
            app.enviar(5) = 2;
            app.adelante.Color = app.RED_COLOR;
            app.parar.Color = app.GREEN_COLOR;
            app.atras.Color = app.RED_COLOR;
        end

        % Button pushed function: AtrsButton
        function AtrsButtonPushed(app, event)
            app.enviar(5) = 3;
            app.adelante.Color = app.RED_COLOR;
            app.parar.Color = app.RED_COLOR;
            app.atras.Color = app.GREEN_COLOR;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Robot_Miguel = uifigure('Visible', 'off');
            app.Robot_Miguel.Name = 'Robot Miguel';
            app.Robot_Miguel.CloseRequestFcn = createCallbackFcn(app, @Robot_MiguelCloseRequest, true);
            app.Image = uiimage(app.Robot_Miguel);
            app.Image.ImageSource = 'Captura de pantalla 2021-02-10 181124.jpg';
            app.Button = uibutton(app.Robot_Miguel, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.AdelanteButton = uibutton(app.Robot_Miguel, 'push');
            app.AdelanteButton.ButtonPushedFcn = createCallbackFcn(app, @AdelanteButtonPushed, true);
            app.AdelanteButton.Text = 'Adelante';
            app.PararButton = uibutton(app.Robot_Miguel, 'push');
            app.PararButton.ButtonPushedFcn = createCallbackFcn(app, @PararButtonPushed, true);
            app.PararButton.Text = 'Parar';
            app.AtrsButton = uibutton(app.Robot_Miguel, 'push');
            app.AtrsButton.ButtonPushedFcn = createCallbackFcn(app, @AtrsButtonPushed, true);
            app.AtrsButton.Text = 'Atrás';
            app.VelocidadLabel = uilabel(app.Robot_Miguel);
            app.VelocidadLabel.Text = 'Velocidad (%)';
            app.VelocidadKnob = uiknob(app.Robot_Miguel, 'continuous');
            app.VelocidadKnob.Limits = [30 100];
            app.VelocidadKnob.ValueChangedFcn = createCallbackFcn(app, @VelocidadKnobValueChanged, true);
            app.VelocidadKnob.Value = 40;
            app.Button_2 = uibutton(app.Robot_Miguel, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.DireccinSliderLabel = uilabel(app.Robot_Miguel);
            app.DireccinSliderLabel.Text = 'Dirección (°)';
            app.DireccinSlider = uislider(app.Robot_Miguel);
            app.DireccinSlider.Limits = [-40 40];
            app.DireccinSlider.ValueChangedFcn = createCallbackFcn(app, @DireccinSliderValueChanged, true);
            app.Button_3 = uibutton(app.Robot_Miguel, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.PosicinSensordeTemperaturaKnobLabel = uilabel(app.Robot_Miguel);
            app.PosicinSensordeTemperaturaKnobLabel.Text = {'Posición Sensor '; 'de Temperatura (°)'};
            app.PosicinSensordeTemperaturaKnob = uiknob(app.Robot_Miguel, 'continuous');
            app.PosicinSensordeTemperaturaKnob.Limits = [-360 360];
            app.PosicinSensordeTemperaturaKnob.MajorTicks = [-360 -270 -180 -90 -45 0 45 90 180 270 360];
            app.PosicinSensordeTemperaturaKnob.ValueChangedFcn = createCallbackFcn(app, @PosicinSensordeTemperaturaKnobValueChanged, true);
            app.Image2 = uiimage(app.Robot_Miguel);
            app.Image2.ImageSource = 'Captura de pantalla 2021-02-14 115809.jpg';
            app.Image3 = uiimage(app.Robot_Miguel);
            app.Image3.ImageSource = 'Captura de pantalla 2021-02-14 115947.jpg';
            app.Knob = uiknob(app.Robot_Miguel, 'continuous');
            app.Knob.Limits = [0 90];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Image4 = uiimage(app.Robot_Miguel);
            app.Image4.ImageSource = 'Captura de pantalla 2021-02-14 115714.jpg';
            app.BDiscon = uibutton(app.Robot_Miguel, 'push');
            app.BDiscon.ButtonPushedFcn = createCallbackFcn(app, @BDisconButtonPushed, true);
            app.BDiscon.Text = 'Desconectar';
            app.PORTDropDownLabel = uilabel(app.Robot_Miguel);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PortList = uidropdown(app.Robot_Miguel);
            app.PortList.Items = {'COM?'};
            app.PortList.Editable = 'on';
            app.PortList.Value = 'COM?';
            app.Image5 = uiimage(app.Robot_Miguel);
            app.Image5.ImageSource = 'Arduino_Serial_03.png';
            app.ConexinLampLabel = uilabel(app.Robot_Miguel);
            app.ConexinLampLabel.Text = 'Conexión';
            app.ConnLamp = uilamp(app.Robot_Miguel);
            app.BCon = uibutton(app.Robot_Miguel, 'push');
            app.BCon.ButtonPushedFcn = createCallbackFcn(app, @BConButtonPushed, true);
            app.BCon.Text = 'Conectar';
            app.Label_amb = uilabel(app.Robot_Miguel);
            app.Label_obj = uilabel(app.Robot_Miguel);
            app.Label_obj.Text = 'Label2';
            app.TemperaturaLabel = uilabel(app.Robot_Miguel);
            app.TemperaturaLabel.Text = 'Temperatura';
            app.AmbienteLabel = uilabel(app.Robot_Miguel);
            app.AmbienteLabel.Text = 'Ambiente ';
            app.ObjetoLabel = uilabel(app.Robot_Miguel);
            app.ObjetoLabel.Text = 'Objeto';
            app.CLabel = uilabel(app.Robot_Miguel);
            app.CLabel.Text = '°C';
            app.CLabel_2 = uilabel(app.Robot_Miguel);
            app.CLabel_2.Text = '°C';
            app.VelociddadmotorDCizquierdoLabel = uilabel(app.Robot_Miguel);
            app.VelociddadmotorDCizquierdoLabel.Text = {'Velociddad motor DC '; 'izquierdo'};
            app.VelociddadmotorDCizquierdoGauge = uigauge(app.Robot_Miguel, 'ninetydegree');
            app.VelociddadmotorDCizquierdoGauge.Limits = [0 200];
            app.VelociddadmotorDCizquierdoGauge.MajorTicks = [0 40 80 120 160 200];
            app.VelociddadmotorDCizquierdoGauge.MajorTickLabels = {'0', '120', '140', '160', '180', '200'};
            app.VelociddadmotorDCizquierdoGauge.MinorTicks = [0 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200];
            app.VelocidadmotorDCderechoGaugeLabel = uilabel(app.Robot_Miguel);
            app.VelocidadmotorDCderechoGaugeLabel.Text = {'Velocidad motor DC'; 'derecho'; ''};
            app.VelocidadmotorDCderechoGauge = uigauge(app.Robot_Miguel, 'ninetydegree');
            app.VelocidadmotorDCderechoGauge.Limits = [0 200];
            app.VelocidadmotorDCderechoGauge.MajorTicks = [0 40 80 120 160 200];
            app.VelocidadmotorDCderechoGauge.MajorTickLabels = {'0', '120', '140', '160', '180', '200'};
            app.VelocidadmotorDCderechoGauge.ScaleDirection = 'counterclockwise';
            app.VelocidadmotorDCderechoGauge.MinorTicks = [0 80 84 88 92 96 100 104 108 112 116 120 124 128 132 136 140 144 148 152 156 160 164 168 172 176 180 184 188 192 196 200];
            app.adelante = uilamp(app.Robot_Miguel);
            app.parar = uilamp(app.Robot_Miguel);
            app.atras = uilamp(app.Robot_Miguel);
            % Show the figure after all components are created
            app.Robot_Miguel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_mini_proyecto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Robot_Miguel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Robot_Miguel)
        end
    end
end